classdef in_ear_test_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        Image7_3                        matlab.ui.control.Image
        Image6_3                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        ThisexperimentcollectshumaninearacousticrefelectiondataLabel_3  matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        HardwareConfigurationTab        matlab.ui.container.Tab
        BCHnktDropDown                  matlab.ui.control.DropDown
        BCHnktDropDownLabel             matlab.ui.control.Label
        Renyi_alphaEditField            matlab.ui.control.NumericEditField
        Renyi_alphaEditFieldLabel       matlab.ui.control.Label
        k_cepstrumDropDown              matlab.ui.control.DropDown
        k_cepstrumDropDownLabel         matlab.ui.control.Label
        HighestFrequencyEditField       matlab.ui.control.NumericEditField
        HighestFrequencyEditFieldLabel  matlab.ui.control.Label
        LowestFrequencyEditField        matlab.ui.control.NumericEditField
        LowestFrequencyEditFieldLabel   matlab.ui.control.Label
        AuthenticationConfigurationLabel  matlab.ui.control.Label
        AuthRepeatNumberEditField       matlab.ui.control.NumericEditField
        AuthRepeatNumberEditFieldLabel  matlab.ui.control.Label
        EnrollRepeatNumberEditField     matlab.ui.control.NumericEditField
        EnrollRepeatNumberEditFieldLabel  matlab.ui.control.Label
        SelectScanSignalDropDown        matlab.ui.control.DropDown
        SelectScanSignalDropDownLabel   matlab.ui.control.Label
        ScanningConfigurationLabel      matlab.ui.control.Label
        ApplyandTestButton              matlab.ui.control.Button
        VolumedBFSSpinner               matlab.ui.control.Spinner
        VolumedBFSSpinnerLabel          matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Lamp_1                          matlab.ui.control.Lamp
        LeftLabel                       matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        HardwareConfigurationLabel      matlab.ui.control.Label
        BufferSIzeEditField             matlab.ui.control.NumericEditField
        BufferSIzeEditFieldLabel        matlab.ui.control.Label
        RecorderChannelMappingEditField  matlab.ui.control.EditField
        RecorderChannelMappingEditFieldLabel  matlab.ui.control.Label
        PlayerChannelMappingEditField   matlab.ui.control.EditField
        PlayerChannelMappingEditFieldLabel  matlab.ui.control.Label
        BitDepthDropDown                matlab.ui.control.DropDown
        BitDepthDropDownLabel           matlab.ui.control.Label
        SampleRateDropDown              matlab.ui.control.DropDown
        SampleRateDropDownLabel         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        DeviceDropDown                  matlab.ui.control.DropDown
        DeviceDropDownLabel             matlab.ui.control.Label
        EnrollTab                       matlab.ui.container.Tab
        FordemonstrationpurposesonlyleftearisusedforEarIDLabel  matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        EnrollmentPageLabel             matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        IamreadytologinButton           matlab.ui.control.Button
        AuthenticationmessageLabel      matlab.ui.control.Label
        Image3_2                        matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        EnrollUpdateConfirmButton       matlab.ui.control.Button
        ScanSuccessLamp                 matlab.ui.control.Lamp
        UsernameEditField               matlab.ui.control.EditField
        ScanButton                      matlab.ui.control.Button
        UIAxes_right                    matlab.ui.control.UIAxes
        UIAxes_left                     matlab.ui.control.UIAxes
        LoginTab                        matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        QuitButton_2                    matlab.ui.control.Button
        ClearButton_4                   matlab.ui.control.Button
        LoginButton_4                   matlab.ui.control.Button
        EarscaningstatusLabel_2         matlab.ui.control.Label
        AuthenticatormessageLabel_2     matlab.ui.control.Label
        Image3_3                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image7_4                        matlab.ui.control.Image
        Image6_4                        matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        UsernameEditField_2             matlab.ui.control.EditField
        ScanButton_2                    matlab.ui.control.Button
        UIAxes_right_2                  matlab.ui.control.UIAxes
        UIAxes_left_2                   matlab.ui.control.UIAxes
        AuthenticationTab               matlab.ui.container.Tab
        ClearButton_5                   matlab.ui.control.Button
        UsernameEditField_3             matlab.ui.control.EditField
        ScanButton_3                    matlab.ui.control.Button
        Image7_5                        matlab.ui.control.Image
        ScanSuccessLamp_2               matlab.ui.control.Lamp
        TextArea_3                      matlab.ui.control.TextArea
        AuthenticationmessageLabel_2    matlab.ui.control.Label
        Image3_4                        matlab.ui.control.Image
        Image4_4                        matlab.ui.control.Image
        FordemonstrationpurposesonlyleftearisusedforEarIDLabel_2  matlab.ui.control.Label
        AuthenticationPageLabel         matlab.ui.control.Label
        HaventenrollButton              matlab.ui.control.Button
        Image6_5                        matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        LogmeinwithEarIDButton          matlab.ui.control.Button
        UIAxes_right_3                  matlab.ui.control.UIAxes
        UIAxes_left_3                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % modules
        module_authenticator
        module_feat_processor
        module_acoustic_scanner
        % params
        LatestAuthMessage
    end

    methods (Access = private)

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath audio/ UI_code/
            addpath UI_code/audioPlay/ UI_code/helperFnc/ UI_code/preCalculate/
            % find audio device
            apr = audioPlayerRecorder;
            devices = getAudioDevices(apr);
            app.DeviceDropDown.Items = devices;
            %
            app.module_acoustic_scanner = AcousticScanner();
            app.module_feat_processor = FeatureProcessor();
            %
            app.LatestAuthMessage = "Welcome!\n";
            app.EnrollUpdateConfirmButton.Enable ="off";
            app.LogmeinwithEarIDButton.Enable = "off";

            app.Lamp_1.Color = '[0.9 0.9 0.9]';
            app.ScanSuccessLamp.Color = "[0.9 0.9 0.9]";
            app.ScanSuccessLamp_2.Color = "[0.9 0.9 0.9]";
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            audiodevreset;
            apr = audioPlayerRecorder;
            devices = getAudioDevices(apr);
            app.DeviceDropDown.Items = devices;
        end

        % Button pushed function: ApplyandTestButton
        function ApplyandTestButtonPushed(app, event)
            %-------------------------Acoustic Scanner----------------------
            
            % load parameters
            params_acoustic_scanner.Device = app.DeviceDropDown.Value;
            params_acoustic_scanner.SampleRate = str2double(app.SampleRateDropDown.Value);
            params_acoustic_scanner.BitDepth = app.BitDepthDropDown.Value;
            params_acoustic_scanner.BufferSize = app.BufferSIzeEditField.Value;
            params_acoustic_scanner.PlayerChannelMapping_str = ...
            app.PlayerChannelMappingEditField.Value;
            params_acoustic_scanner.RecorderChannelMapping_str = ...
                app.RecorderChannelMappingEditField.Value;
            params_acoustic_scanner.volume = app.VolumedBFSSpinner.Value;
            % scan
            params_acoustic_scanner.scan_signal_type = app.SelectScanSignalDropDown.Value;
            params_acoustic_scanner.scan_enroll_number = app.EnrollRepeatNumberEditField.Value;
            params_acoustic_scanner.scan_auth_number = app.AuthRepeatNumberEditField.Value;

            % initiate module_acoustic_scanner
            app.module_acoustic_scanner = app.module_acoustic_scanner.initDevice(params_acoustic_scanner);
            status_success = app.module_acoustic_scanner.testDevice();
            if status_success
                app.Lamp_1.Color = 'green';
            else
                app.Lamp_1.Color = 'red';
            end

            % --------------------------------Authenticator --------------------------------
            % load parameters
            params_authenticator.frequency_range_low = app.LowestFrequencyEditField.Value;
            params_authenticator.frequency_range_high = app.HighestFrequencyEditField.Value;
            params_authenticator.first_k_cepstrum = str2double(app.k_cepstrumDropDown.Value);
            params_authenticator.renyi_alpha = app.Renyi_alphaEditField.Value;
            % BCH param
            bch_param_vec = eval(app.BCHnktDropDown.Value);

            params_authenticator.bch_n = bch_param_vec(1);
            params_authenticator.bch_k = bch_param_vec(2);
            params_authenticator.bch_t = bch_param_vec(3);

            % initialize module_authenticator
            app.module_authenticator = Authenticator(params_authenticator);
            %------------------------------------------------------------------------------
        end

        % Value changed function: VolumedBFSSpinner
        function VolumedBFSSpinnerValueChanged(app, event)

        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            % app.ScanSuccessLamp = "[0.9 0.9 0.9]";
            app.UsernameEditField.Enable = "off";
            
            app.module_acoustic_scanner = app.module_acoustic_scanner.scanEarIR("enroll");
            if app.module_acoustic_scanner.data_buffer.status == "enroll"
                app.ScanSuccessLamp.Color = "green";
                app.EnrollUpdateConfirmButton.Enable ="on";
                %
                dataIR = app.module_acoustic_scanner.data_buffer.data;
                dataFR = app.module_feat_processor.ir2fr(dataIR);
                % plot
                UIAxes = ["app.UIAxes_left" "app.UIAxes_right"];
                for j = 1:length(dataFR)
                    sampleData = mean(dataFR{j},1);
                    eval("plot("+UIAxes(j)+", app.module_feat_processor.freqVec, sampleData)");
                    eval("xlabel("+UIAxes(j)+", 'Frequency (Hz)');");
                    eval("ylabel("+UIAxes(j)+", 'Ear Canal Response');");
                end
            end
        end

        % Callback function
        function AddUserButtonPushed(app, event)

        end

        % Button pushed function: EnrollUpdateConfirmButton
        function EnrollUpdateConfirmButtonPushed(app, event)
          
            % get username
            userName = app.UsernameEditField.Value;
            % get scan signal data
            dataIR = app.module_acoustic_scanner.data_buffer.data;
            dataFR = app.module_feat_processor.ir2fr(dataIR);
            % save data into enrollment_database
            app.module_authenticator = app.module_authenticator.enrollUser(userName, dataFR);

            % update status
            app.LatestAuthMessage = app.LatestAuthMessage + app.module_authenticator.auth_status;
            app.TextArea.Value = app.LatestAuthMessage;

            % reset to enroll another user
            cla(app.UIAxes_left)
            cla(app.UIAxes_right)
            app.module_acoustic_scanner = app.module_acoustic_scanner.clearBuffer();
            app.UsernameEditField.Value = {};
            app.UsernameEditField.Enable = "on";
            app.EnrollUpdateConfirmButton.Enable ="off";
            app.ScanSuccessLamp.Color = "[0.9 0.9 0.9]";
        end

        % Callback function
        function ClearButton_3Pushed(app, event)
            % reset to enroll another user
            cla(app.UIAxes_left)
            cla(app.UIAxes_right)
            app.module_acoustic_scanner = app.module_acoustic_scanner.clearBuffer();
            app.UsernameEditField.Value = {};
            app.UsernameEditField.Enable = "on";
            app.EnrollUpdateConfirmButton.Enable ="off";
            app.ScanSuccessLamp.Color = "[0.9 0.9 0.9]";
        end

        % Button pushed function: ScanButton_2
        function ScanButton_2Pushed(app, event)
            app.module_acoustic_scanner = app.module_acoustic_scanner.scanEarIR("auth");
            if app.module_acoustic_scanner.data_buffer.status == "auth"
                dataIR = app.module_acoustic_scanner.data_buffer.data;
                dataFR = app.module_feat_processor.ir2fr(dataIR);
                % plot
                UIAxes = ["app.UIAxes_left_2" "app.UIAxes_right_2"];
                for j = 1:length(dataFR)
                    sampleData = mean(dataFR{j},1);
                    eval("plot("+UIAxes(j)+", app.module_feat_processor.freqVec, sampleData)");
                    eval("xlabel("+UIAxes(j)+", 'Frequency (Hz)');");
                    eval("ylabel("+UIAxes(j)+", 'Ear Canal Response');");
                end
            end
        end

        % Button pushed function: LoginButton_4
        function LoginButton_4Pushed(app, event)
            % get username
            userName = app.UsernameEditField_2.Value;
            % get scan signal data
            dataIR = app.module_acoustic_scanner.data_buffer.data;
            dataFR = app.module_feat_processor.ir2fr(dataIR);
            % save data into enrollment_database
            app.module_authenticator = app.module_authenticator.authUser(userName, dataFR);

            % update status
            app.TextArea.Value = app.module_authenticator.auth_status;

            % clear buffer data
            app.module_acoustic_scanner = app.module_acoustic_scanner.clearBuffer();

            % print result
            app.TextArea_2.Value = app.module_authenticator.auth_status;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.module_acoustic_scanner = app.module_acoustic_scanner.clearBuffer();
            app.UsernameEditField.Enable = "on";
            cla(app.UIAxes_left)
            cla(app.UIAxes_right)
        end

        % Button pushed function: ScanButton_3
        function ScanButton_3Pushed(app, event)
            app.UsernameEditField_3.Enable = "off";
            
            app.module_acoustic_scanner = app.module_acoustic_scanner.scanEarIR("auth");
            if app.module_acoustic_scanner.data_buffer.status == "auth"
                %                %
                dataIR = app.module_acoustic_scanner.data_buffer.data;
                dataFR = app.module_feat_processor.ir2fr(dataIR);
                % plot
                UIAxes = ["app.UIAxes_left_3" "app.UIAxes_right_3"];
                for j = 1:length(dataFR)
                    sampleData = mean(dataFR{j},1);
                    eval("plot("+UIAxes(j)+", app.module_feat_processor.freqVec, sampleData)");
                    eval("xlabel("+UIAxes(j)+", 'Frequency (Hz)');");
                    eval("ylabel("+UIAxes(j)+", 'Ear Canal Response');");
                end
                %
                app.ScanSuccessLamp_2.Color = "green";
                app.LogmeinwithEarIDButton.Enable ="on";
            end
        end

        % Button pushed function: ClearButton_5
        function ClearButton_5Pushed(app, event)
            % reset to enroll another user
            cla(app.UIAxes_left_3)
            cla(app.UIAxes_right_3)
            app.module_acoustic_scanner = app.module_acoustic_scanner.clearBuffer();
            app.UsernameEditField_3.Value = {};
            app.UsernameEditField_3.Enable = "on";
            app.LogmeinwithEarIDButton.Enable ="off";
            app.ScanSuccessLamp_2.Color = "[0.9 0.9 0.9]";
        end

        % Button pushed function: LogmeinwithEarIDButton
        function LogmeinwithEarIDButtonPushed(app, event)
            % get username
            userName = app.UsernameEditField_3.Value;
            % get scan signal data
            dataIR = app.module_acoustic_scanner.data_buffer.data;
            dataFR = app.module_feat_processor.ir2fr(dataIR);
            % authenticate a user
            app.module_authenticator = app.module_authenticator.authUser(userName, dataFR);

            % update status
            app.LatestAuthMessage = app.LatestAuthMessage + app.module_authenticator.auth_status;
            app.TextArea_3.Value = app.LatestAuthMessage;

            % reset to enroll another user
            cla(app.UIAxes_left_3)
            cla(app.UIAxes_right_3)
            app.module_acoustic_scanner = app.module_acoustic_scanner.clearBuffer();
            app.UsernameEditField_3.Value = {};
            app.UsernameEditField_3.Enable = "on";
            app.LogmeinwithEarIDButton.Enable ="off";
            app.ScanSuccessLamp_2.Color = "[0.9 0.9 0.9]";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.Image2_2 = uiimage(app.WelcomeTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'img', 'secure.png');
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel_3 = uilabel(app.WelcomeTab);
            app.ThisexperimentcollectshumaninearacousticrefelectiondataLabel_3.Text = 'This experiment collects human in-ear acoustic refelection data, or in-ear acoustic. The data is collected by our hand-crafted in-ear device: feedfoward probe microphone mounted on an earplug. This research aims to explore the user-level uniqueness of in-ear acoustic and the potential use for user authentication. Thank you for  your contribution!';
            app.Image5 = uiimage(app.WelcomeTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'img', 'hearing.png');
            app.Image6_3 = uiimage(app.WelcomeTab);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, 'img', 'logo2.png');
            app.Image7_3 = uiimage(app.WelcomeTab);
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, 'img', 'logo1.png');
            app.Image_3 = uiimage(app.WelcomeTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'img', 'uh_red.png');
            app.HardwareConfigurationTab = uitab(app.TabGroup);
            app.HardwareConfigurationTab.Title = 'Hardware Configuration';
            app.DeviceDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.HardwareConfigurationTab);
            app.Button = uibutton(app.HardwareConfigurationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'img', 'refresh.png');
            app.Button.Text = '';
            app.SampleRateDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.SampleRateDropDownLabel.Text = 'SampleRate';
            app.SampleRateDropDown = uidropdown(app.HardwareConfigurationTab);
            app.SampleRateDropDown.Items = {'48000', '44100', '32000', '16000', '8000'};
            app.SampleRateDropDown.Value = '48000';
            app.BitDepthDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.BitDepthDropDownLabel.Text = 'BitDepth';
            app.BitDepthDropDown = uidropdown(app.HardwareConfigurationTab);
            app.BitDepthDropDown.Items = {'16-bit integer', '8-bit integer'};
            app.BitDepthDropDown.Value = '16-bit integer';
            app.PlayerChannelMappingEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.PlayerChannelMappingEditFieldLabel.Text = 'PlayerChannelMapping';
            app.PlayerChannelMappingEditField = uieditfield(app.HardwareConfigurationTab, 'text');
            app.PlayerChannelMappingEditField.Value = '[1 2]';
            app.RecorderChannelMappingEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.RecorderChannelMappingEditFieldLabel.Text = 'RecorderChannelMapping';
            app.RecorderChannelMappingEditField = uieditfield(app.HardwareConfigurationTab, 'text');
            app.RecorderChannelMappingEditField.Value = '[1 2]';
            app.BufferSIzeEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.BufferSIzeEditFieldLabel.Text = 'BufferSIze';
            app.BufferSIzeEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.BufferSIzeEditField.Value = 1024;
            app.HardwareConfigurationLabel = uilabel(app.HardwareConfigurationTab);
            app.HardwareConfigurationLabel.Text = 'Hardware Configuration';
            app.Image4 = uiimage(app.HardwareConfigurationTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'img', 'left_ear.png');
            app.LeftLabel = uilabel(app.HardwareConfigurationTab);
            app.LeftLabel.Text = 'Left';
            app.Lamp_1 = uilamp(app.HardwareConfigurationTab);
            app.Image3 = uiimage(app.HardwareConfigurationTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'img', 'right_ear.png');
            app.RightLabel = uilabel(app.HardwareConfigurationTab);
            app.RightLabel.Text = 'Right';
            app.VolumedBFSSpinnerLabel = uilabel(app.HardwareConfigurationTab);
            app.VolumedBFSSpinnerLabel.Text = 'Volume (dBFS)';
            app.VolumedBFSSpinner = uispinner(app.HardwareConfigurationTab);
            app.VolumedBFSSpinner.Step = 10;
            app.VolumedBFSSpinner.Limits = [-50 0];
            app.VolumedBFSSpinner.ValueChangedFcn = createCallbackFcn(app, @VolumedBFSSpinnerValueChanged, true);
            app.VolumedBFSSpinner.Value = -35;
            app.ApplyandTestButton = uibutton(app.HardwareConfigurationTab, 'push');
            app.ApplyandTestButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyandTestButtonPushed, true);
            app.ApplyandTestButton.Text = 'Apply and Test';
            app.ScanningConfigurationLabel = uilabel(app.HardwareConfigurationTab);
            app.ScanningConfigurationLabel.Text = 'Scanning Configuration';
            app.SelectScanSignalDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.SelectScanSignalDropDownLabel.Text = 'Select Scan Signal';
            app.SelectScanSignalDropDown = uidropdown(app.HardwareConfigurationTab);
            app.SelectScanSignalDropDown.Items = {'MLS', 'chirp'};
            app.SelectScanSignalDropDown.Value = 'MLS';
            app.EnrollRepeatNumberEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.EnrollRepeatNumberEditFieldLabel.Text = 'Enroll Repeat Number';
            app.EnrollRepeatNumberEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.EnrollRepeatNumberEditField.Value = 10;
            app.AuthRepeatNumberEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.AuthRepeatNumberEditFieldLabel.Text = 'Auth Repeat Number';
            app.AuthRepeatNumberEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.AuthRepeatNumberEditField.Value = 3;
            app.AuthenticationConfigurationLabel = uilabel(app.HardwareConfigurationTab);
            app.AuthenticationConfigurationLabel.Text = 'Authentication Configuration';
            app.LowestFrequencyEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.LowestFrequencyEditFieldLabel.Text = 'Lowest Frequency';
            app.LowestFrequencyEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.LowestFrequencyEditField.Value = 1000;
            app.HighestFrequencyEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.HighestFrequencyEditFieldLabel.Text = 'Highest Frequency';
            app.HighestFrequencyEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.HighestFrequencyEditField.Value = 8000;
            app.k_cepstrumDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.k_cepstrumDropDownLabel.Text = 'k_cepstrum';
            app.k_cepstrumDropDown = uidropdown(app.HardwareConfigurationTab);
            app.k_cepstrumDropDown.Items = {'256', '512', '1024'};
            app.k_cepstrumDropDown.Editable = 'on';
            app.k_cepstrumDropDown.Value = '256';
            app.Renyi_alphaEditFieldLabel = uilabel(app.HardwareConfigurationTab);
            app.Renyi_alphaEditFieldLabel.Text = 'Renyi_alpha';
            app.Renyi_alphaEditField = uieditfield(app.HardwareConfigurationTab, 'numeric');
            app.Renyi_alphaEditField.Editable = 'off';
            app.BCHnktDropDownLabel = uilabel(app.HardwareConfigurationTab);
            app.BCHnktDropDownLabel.Text = 'BCH(n,k,t)';
            app.BCHnktDropDown = uidropdown(app.HardwareConfigurationTab);
            app.BCHnktDropDown.Items = {'[63,36,5]', '[127,64,10]', '[255,131,18]'};
            app.BCHnktDropDown.Editable = 'on';
            app.BCHnktDropDown.Value = '[63,36,5]';
            app.EnrollTab = uitab(app.TabGroup);
            app.EnrollTab.Title = 'Enroll';
            app.UIAxes_left = uiaxes(app.EnrollTab);
            app.UIAxes_left.XTick = [];
            app.UIAxes_left.YTick = [];
            app.UIAxes_right = uiaxes(app.EnrollTab);
            zlabel(app.UIAxes_right, 'Z')
            app.UIAxes_right.XTick = [];
            app.UIAxes_right.YTick = [];
            app.ScanButton = uibutton(app.EnrollTab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Icon = fullfile(pathToMLAPP, 'img', 'scanner.png');
            app.ScanButton.Text = '';
            app.UsernameEditField = uieditfield(app.EnrollTab, 'text');
            app.UsernameEditField.Placeholder = 'Enter User Name';
            app.ScanSuccessLamp = uilamp(app.EnrollTab);
            app.EnrollUpdateConfirmButton = uibutton(app.EnrollTab, 'push');
            app.EnrollUpdateConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @EnrollUpdateConfirmButtonPushed, true);
            app.EnrollUpdateConfirmButton.Text = 'Enroll/Update Confirm';
            app.Image = uiimage(app.EnrollTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'uh_red.png');
            app.Image6 = uiimage(app.EnrollTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'img', 'logo2.png');
            app.Image7 = uiimage(app.EnrollTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'img', 'logo1.png');
            app.Image4_2 = uiimage(app.EnrollTab);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'img', 'left_ear.png');
            app.Image3_2 = uiimage(app.EnrollTab);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'img', 'right_ear.png');
            app.AuthenticationmessageLabel = uilabel(app.EnrollTab);
            app.AuthenticationmessageLabel.Text = 'Authentication message';
            app.IamreadytologinButton = uibutton(app.EnrollTab, 'push');
            app.IamreadytologinButton.Text = 'I am ready to log in';
            app.TextArea = uitextarea(app.EnrollTab);
            app.TextArea.Editable = 'off';
            app.EnrollmentPageLabel = uilabel(app.EnrollTab);
            app.EnrollmentPageLabel.Text = 'Enrollment Page';
            app.ClearButton = uibutton(app.EnrollTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = fullfile(pathToMLAPP, 'img', 'refresh.png');
            app.ClearButton.Text = '';
            app.FordemonstrationpurposesonlyleftearisusedforEarIDLabel = uilabel(app.EnrollTab);
            app.FordemonstrationpurposesonlyleftearisusedforEarIDLabel.Text = 'For demonstration purposes, only left ear is used for EarID.';
            app.LoginTab = uitab(app.TabGroup);
            app.LoginTab.Title = 'Login';
            app.UIAxes_left_2 = uiaxes(app.LoginTab);
            app.UIAxes_left_2.XTick = [];
            app.UIAxes_left_2.YTick = [];
            app.UIAxes_right_2 = uiaxes(app.LoginTab);
            zlabel(app.UIAxes_right_2, 'Z')
            app.UIAxes_right_2.XTick = [];
            app.UIAxes_right_2.YTick = [];
            app.ScanButton_2 = uibutton(app.LoginTab, 'push');
            app.ScanButton_2.ButtonPushedFcn = createCallbackFcn(app, @ScanButton_2Pushed, true);
            app.ScanButton_2.Icon = fullfile(pathToMLAPP, 'img', 'scanner.png');
            app.ScanButton_2.Text = '';
            app.UsernameEditField_2 = uieditfield(app.LoginTab, 'text');
            app.UsernameEditField_2.Placeholder = 'Enter username';
            app.Image_4 = uiimage(app.LoginTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'img', 'uh_red.png');
            app.Image6_4 = uiimage(app.LoginTab);
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, 'img', 'logo2.png');
            app.Image7_4 = uiimage(app.LoginTab);
            app.Image7_4.ImageSource = fullfile(pathToMLAPP, 'img', 'logo1.png');
            app.Image4_3 = uiimage(app.LoginTab);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'img', 'left_ear.png');
            app.Image3_3 = uiimage(app.LoginTab);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'img', 'right_ear.png');
            app.AuthenticatormessageLabel_2 = uilabel(app.LoginTab);
            app.AuthenticatormessageLabel_2.Text = 'Authenticator message';
            app.EarscaningstatusLabel_2 = uilabel(app.LoginTab);
            app.EarscaningstatusLabel_2.Text = 'Earscaning status';
            app.LoginButton_4 = uibutton(app.LoginTab, 'push');
            app.LoginButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_4Pushed, true);
            app.LoginButton_4.Text = 'Login';
            app.ClearButton_4 = uibutton(app.LoginTab, 'push');
            app.ClearButton_4.Text = 'Clear';
            app.QuitButton_2 = uibutton(app.LoginTab, 'push');
            app.QuitButton_2.Enable = 'off';
            app.QuitButton_2.Text = 'Quit';
            app.TextArea_2 = uitextarea(app.LoginTab);
            app.TextArea_2.Editable = 'off';
            app.AuthenticationTab = uitab(app.TabGroup);
            app.AuthenticationTab.Title = 'Authentication';
            app.UIAxes_left_3 = uiaxes(app.AuthenticationTab);
            app.UIAxes_left_3.XTick = [];
            app.UIAxes_left_3.YTick = [];
            app.UIAxes_right_3 = uiaxes(app.AuthenticationTab);
            zlabel(app.UIAxes_right_3, 'Z')
            app.UIAxes_right_3.XTick = [];
            app.UIAxes_right_3.YTick = [];
            app.LogmeinwithEarIDButton = uibutton(app.AuthenticationTab, 'push');
            app.LogmeinwithEarIDButton.ButtonPushedFcn = createCallbackFcn(app, @LogmeinwithEarIDButtonPushed, true);
            app.LogmeinwithEarIDButton.Text = 'Log me in with EarID!';
            app.Image_5 = uiimage(app.AuthenticationTab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'img', 'uh_red.png');
            app.Image6_5 = uiimage(app.AuthenticationTab);
            app.Image6_5.ImageSource = fullfile(pathToMLAPP, 'img', 'logo2.png');
            app.HaventenrollButton = uibutton(app.AuthenticationTab, 'push');
            app.HaventenrollButton.Text = 'Haven''t enroll?';
            app.AuthenticationPageLabel = uilabel(app.AuthenticationTab);
            app.AuthenticationPageLabel.Text = 'Authentication Page';
            app.FordemonstrationpurposesonlyleftearisusedforEarIDLabel_2 = uilabel(app.AuthenticationTab);
            app.FordemonstrationpurposesonlyleftearisusedforEarIDLabel_2.Text = 'For demonstration purposes, only left ear is used for EarID.';
            app.Image4_4 = uiimage(app.AuthenticationTab);
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'img', 'left_ear.png');
            app.Image3_4 = uiimage(app.AuthenticationTab);
            app.Image3_4.ImageSource = fullfile(pathToMLAPP, 'img', 'right_ear.png');
            app.AuthenticationmessageLabel_2 = uilabel(app.AuthenticationTab);
            app.AuthenticationmessageLabel_2.Text = 'Authentication message';
            app.TextArea_3 = uitextarea(app.AuthenticationTab);
            app.TextArea_3.Editable = 'off';
            app.ScanSuccessLamp_2 = uilamp(app.AuthenticationTab);
            app.Image7_5 = uiimage(app.AuthenticationTab);
            app.Image7_5.ImageSource = fullfile(pathToMLAPP, 'img', 'logo1.png');
            app.ScanButton_3 = uibutton(app.AuthenticationTab, 'push');
            app.ScanButton_3.ButtonPushedFcn = createCallbackFcn(app, @ScanButton_3Pushed, true);
            app.ScanButton_3.Icon = fullfile(pathToMLAPP, 'img', 'scanner.png');
            app.ScanButton_3.Text = '';
            app.UsernameEditField_3 = uieditfield(app.AuthenticationTab, 'text');
            app.UsernameEditField_3.Placeholder = 'Enter User Name';
            app.ClearButton_5 = uibutton(app.AuthenticationTab, 'push');
            app.ClearButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_5Pushed, true);
            app.ClearButton_5.Icon = fullfile(pathToMLAPP, 'img', 'refresh.png');
            app.ClearButton_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = in_ear_test_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end